classdef MicroProjectAppTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MembersPanel          matlab.ui.container.Panel
        AyaRedaAbdallahSaiedAbdelazizMohammadLabel  matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        UARTUSBEMULATORLabel  matlab.ui.control.Label
        ReadFilesButton       matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HelperTextAreaValueChanged(app, event)

            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
               %Open config.json 
               json_filename = 'conf.json';
               json_config = jsondecode(fileread(json_filename));           
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
               % Open  input.txt file
               filename = 'inputdata.txt';
               fileID = fopen(filename);
               formatspec = '%c';
               Array = fscanf(fileID,formatspec);                                     
               fclose(fileID);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
                %Solve PDF Questions
               UART.NumberOfNonDataBits=0;
               UART.NumberOfDataBits=json_config(1).inputs.data_bits;
               UART.TotalNumberOfBits=0;
               UART.Bitduration = json_config(1).inputs.bit_duration;
               
               USB.Bitduration = json_config(2).inputs.bit_duration;
               USB.NumberOfNonDataBits=30;
               USB.NumberOfDataBits=1024;
               USB.TotalNumberOfBits = USB.NumberOfNonDataBits+USB.NumberOfDataBits;
               
               if(json_config(1).inputs.parity == "none")
                   UART.NumberOfNonDataBits= json_config(1).inputs.stop_bits + 1; %start and Stop Bits
               else
                   UART.NumberOfNonDataBits= json_config(1).inputs.stop_bits + 2; %start , parity and number of stop bits
                   end
               UART.TotalNumberOfBits = UART.NumberOfNonDataBits + UART.NumberOfDataBits;
               
               
                 % define arrays to be plotted for plotting Questions
                OUTPUT_UART_PLOT_TotalTxTime = [ 0 0 0 0 0 ];
                OUTPUT_USB_PLOT_TotalTxTime = [ 0 0 0 0 0];
                OUTPUT_UART_PLOT_OVERHEAD = [ 0 0 0 0 0 ];   
                OUTPUT_USB_PLOT_OVERHEAD = [ 0 0 0 0 0];
                OUTPUT_USB_DataReminder = [ 0 0 0 0 0]; 
                
                file_size = 1280 : 1 : 3001;
               
               for counter = 1 : 1722
               OUTPUT_UART_PLOT_TotalTxTime(counter) = UART.Bitduration * UART.TotalNumberOfBits * file_size(counter) ;
               OUTPUT_USB_PLOT_TotalTxTime(counter) =  USB.Bitduration  * USB.TotalNumberOfBits * file_size(counter)/128;
               end     
               for counter = 1 : 1722
                   OUTPUT_UART_PLOT_OVERHEAD(counter) = ( (UART.NumberOfNonDataBits * file_size(counter)) / (UART.TotalNumberOfBits * file_size(counter)));   
                   OUTPUT_USB_PACKETS =   floor (( file_size(counter) / 128)) ; 
                   OUTPUT_USB_DataReminder(counter) = mod( file_size(counter) , 128 ) ;
                   if ( OUTPUT_USB_DataReminder(counter) == 0 )
                   format longg
                   OUTPUT_USB_PLOT_OVERHEAD(counter) = (( USB.NumberOfNonDataBits * OUTPUT_USB_PACKETS ) / (( USB.NumberOfNonDataBits * OUTPUT_USB_PACKETS ) + ( OUTPUT_USB_PACKETS * 128 * 8)));
                   elseif ( OUTPUT_USB_DataReminder(counter) > 0 )
                    format longg
                      OUTPUT_USB_PLOT_OVERHEAD(counter) = (( USB.NumberOfNonDataBits * (OUTPUT_USB_PACKETS + 1)  ) / (( USB.NumberOfNonDataBits * (OUTPUT_USB_PACKETS+1) ) + ( OUTPUT_USB_PACKETS * 128 * 8)));
                   end
               end
               
               if(json_config(2).protocol_name == "USB" && json_config(1).protocol_name == "UART")
                   
               %Plotting the Plotting Questions requirments              
               tiledlayout(2,2);
               nexttile;
               plot(file_size,OUTPUT_UART_PLOT_TotalTxTime);
               title('UART Transmit time');
               xlabel('File Size');
               nexttile;
               plot(file_size,OUTPUT_USB_PLOT_TotalTxTime);
               title('USB Transmit time');
               xlabel('File Size');
               nexttile;
               plot(file_size,OUTPUT_UART_PLOT_OVERHEAD);
               title('UART Overhead');
               xlabel('File Size');
               ylabel('Overhead');
               nexttile;
               plot(file_size,OUTPUT_USB_PLOT_OVERHEAD);
               title('USB Overhead'); 
               xlabel('File Size');
               ylabel('Overhead');
               end
               
              %Define an array of struct to use it for generation of
               %output.json file
               OUTPUT(1).protocol_name= "UART";
               
               OUTPUT(1).outputs.total_tx_time = OUTPUT_UART_PLOT_TotalTxTime(1);
               OUTPUT(1).outputs.overhead = OUTPUT_UART_PLOT_OVERHEAD(1) * 100 ;
               OUTPUT(1).outputs.efficiency = 100 - OUTPUT_UART_PLOT_OVERHEAD(1) * 100 ;
               
               OUTPUT(2).protocol_name= "USB";
               
               OUTPUT(2).outputs.total_tx_time = OUTPUT_USB_PLOT_TotalTxTime(1);
               OUTPUT(2).outputs.overhead = OUTPUT_USB_PLOT_OVERHEAD(1) * 100 ;
               OUTPUT(2).outputs.efficiency = 100 - OUTPUT_USB_PLOT_OVERHEAD(1) * 100 ;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               % Read Data
               Element1=dec2bin(Array([1]),8);        % convert intered charater into binary
               Element2=dec2bin(Array([2]),8);        % convert intered charater into binary
               
               Element1 = str2double(Element1);       % make the binary (1,0) numbers not characters 
               Element2 = str2double(Element2);       % make the binary (1,0) numbers not characters 

              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              if(json_config(2).protocol_name == "USB")
                  
              %Remove Error Message   
               app.Label_2.Text = " Successful Plotting Operation";
               app.Label_2.FontColor = [0.39,0.83,0.07];
              
              
              USB.synch_pattern = [ 0 0 0 0 0 0 0 1];
              USB.PID = [0 0 0 0 0 0 0 1];
              USB.PID2 = [0 0 0 0 0 0 1 0];
              USB.EOP = [ 0 0 0];
              USB.addressing = json_config(2).inputs.destination_address;  
              USB.addressing = dec2bin(USB.addressing,11);
              USB.addressing = num2str(USB.addressing)-'0';
               out = reshape(Array,1280,[]);                            %Format the text into characters each on a line
               out = logical (dec2bin(out,8)-'0');                      % convert those characters into their binary equivalent
               out = reshape(out',[1 size(out,1) * size(out,2)]);       % convert it back to 1 dimensional array
               counter_data = 1;
              for counter = 1 :1024
                  USB.data(counter) = out(counter);
              end
              for counter = 1025:2048
                  USB.data2(counter_data) = out(counter);  
                  counter_data = counter_data +1;
              end
              
              USB.data = flip(USB.data);
              USB.data2 = flip(USB.data2);
              USB_frame = [USB.synch_pattern USB.PID USB.addressing USB.data USB.EOP USB.synch_pattern USB.PID2 USB.addressing USB.data2 USB.EOP];  
              USB_frame = regexprep(char(USB_frame+'0'),'111111','1111110')-'0'; 
              USB.output = [1 USB.synch_pattern USB.PID USB.addressing USB.data USB.EOP USB.synch_pattern USB.PID2 USB.addressing  USB.data2 USB.EOP]; %initial value for the output
              USB.outputdiff = ~ USB.output; %differential output
              USB.frame_length = length(USB_frame);
              USB.output_length = length(USB.output);
              USB.EOP_index = floor ( ((USB.frame_length)/2)-2 );
              
              % Loop for toggling the bits to generate the packets to be
              % plotted.
              for counter = 1 : USB.frame_length
                  if(USB_frame(counter)== 0 && counter == USB.EOP_index )
                      USB.output(counter+1)= 0;
                      if(USB.EOP_index == 1052)
                          USB.EOP_index = USB.EOP_index+1; % 1053
                      elseif(USB.EOP_index == 1053)
                          USB.EOP_index = 2107;
                      elseif(USB.EOP_index == 2107)
                          USB.EOP_index = USB.EOP_index+1;     
                      end
                 
                  elseif ( USB_frame(counter) == 1 )
                      USB.output(counter+1)= USB.output(counter);
                  elseif (USB_frame(counter) == 0)
                      USB.output(counter+1) = ~USB.output(counter);
                  end
              end
              
              for counter = 1: USB.output_length
                  if(counter == 1053 || counter == 1054 || counter == 2108 || counter == 2109 )
                      USB.outputdiff(counter) = 0;
                  else
                      USB.outputdiff(counter) = ~ USB.output(counter);
                  end
                  
              end
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              %Draw USB Packets  
              stairs(app.UIAxes2,[USB.output,USB.output(end)]+1.25);
              hold(app.UIAxes2,'on');
              stairs(app.UIAxes2,[USB.outputdiff,USB.outputdiff(end)]);
              hold(app.UIAxes2,'off');
              xlim(app.UIAxes2,[1,100]);
              
             
              %Plot Transmition time & Overhead
               tiledlayout(2,2);
               nexttile;
               plot(file_size,OUTPUT_USB_PLOT_TotalTxTime);
               title('USB Transmit time');
               xlabel('File Size');
               nexttile;
               plot(file_size,OUTPUT_USB_PLOT_OVERHEAD);
               title('USB Overhead'); 
               xlabel('File Size');
               ylabel('Overhead');
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              %Handle Error
              else
              app.Label_2.Text = "Error: Undefined Protocol Entry";
              app.Label_2.FontColor = [1.00,0.00,0.00];
              frame2 = [ 0 0 0 0];
              stairs (app.UIAxes2,[frame2,frame2(end)]); 
              end              
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              if( json_config(1).protocol_name == "UART")
                  
               %Remove Error Message  
               app.Label.Text = " Successful Plotting Operation ";
               app.Label.FontColor = [0.39,0.83,0.07];


               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                                            
                % Data               
              % data = num2str(Element1)-'0';          % seperate them with spaces to be an array 
               %data2 = num2str(Element2)-'0';         % seperate them with spaces to be an array 
               
               data = logical(dec2bin(Array([1]),8)-'0');
               data2 = logical(dec2bin(Array([2]),8)-'0');
               
               %Modify Data for First Element (data)
               if(json_config(1).inputs.data_bits == 8 && length(data) == 7)      % make the data 8 bits
                     data = [ 0 data ] ;                
               elseif((json_config(1).inputs.data_bits == 8 && length(data) == 8)|| (json_config(1).inputs.data_bits == 7 && length(data) == 7))  
                   % do nth
               elseif(json_config(1).inputs.data_bits == 8 && length(data) == 6)  % make the data 8 bits
                     data = [0 0 data];     
                elseif(json_config(1).inputs.data_bits == 7 && length(data) == 6)  % make the data 7 bits
                     data = [0 data];                     
               end
               data = flip(data);                   % flip data to make LSB is the MSB and vice versa
               
                %Modify Data for Second Element (data2)
               if(json_config(1).inputs.data_bits == 8 && length(data2) == 7)      % make the data 8 bits
                     data2 = [ 0 data2 ] ;                
               elseif((json_config(1).inputs.data_bits == 8 && length(data2) == 8) || (json_config(1).inputs.data_bits == 7 && length(data2) == 7))  
                   % do nth       
               elseif(json_config(1).inputs.data_bits == 8 && length(data2) == 6)  % make the data 8 bits
                     data2 = [0 0 data2];  
               elseif(json_config(1).inputs.data_bits == 7 && length(data2) == 6)  % make the data 8 bits
                     data2 = [0 data2];        
               end
               data2 = flip(data2);                   % flip data to make LSB is the MSB and vice versa  
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Constants
               idle_bit = 1;
               start_bit = 0;
               parity_bit = 0;
               stop_bit = 1;
               
               idle_start_bit = [idle_bit start_bit];
               Onestop_Noparity_idle_bit = [stop_bit idle_bit];
               Twostop_Noparity_idle_bit = [stop_bit stop_bit idle_bit];
               Onestop_parity_idle_bit = [0 0 0];
               Twostop_parity_idle_bit = [0 0 0];
               
               %Frame
               frame2 = [start_bit data2  Onestop_Noparity_idle_bit];
               frame= [ idle_start_bit data Onestop_Noparity_idle_bit ]; 
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               %Create Frame Format for second element to be displayed
               if(json_config(1).inputs.stop_bits == 1 && json_config(1).inputs.parity == "none" )
                    frame2 = [ start_bit data2 Onestop_Noparity_idle_bit];                 
               elseif (json_config(1).inputs.stop_bits == 2 && json_config(1).inputs.parity == "none")
                    frame2 = [ start_bit data2 Twostop_Noparity_idle_bit];
               elseif (json_config(1).inputs.stop_bits == 1 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                    number_of_ones = nnz(data2);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                 Onestop_parity_idle_bit = [parity_bit stop_bit  idle_bit];  
                 frame2 = [ start_bit data2 Onestop_parity_idle_bit];
                         
               elseif (json_config(1).inputs.stop_bits == 2 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                      number_of_ones = nnz(data2);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                    Twostop_parity_idle_bit = [parity_bit stop_bit stop_bit  idle_bit];     
                    frame2 = [ start_bit data2 Twostop_parity_idle_bit];                    
               end   
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Create Frame Format for first element to be displayed
                %based on second frame
               if(json_config(1).inputs.stop_bits == 1 && json_config(1).inputs.parity == "none" )
                    frame = [ idle_start_bit data Onestop_Noparity_idle_bit frame2];                 
               elseif (json_config(1).inputs.stop_bits == 2 && json_config(1).inputs.parity == "none")
                    frame = [ idle_start_bit data Twostop_Noparity_idle_bit frame2];
               elseif (json_config(1).inputs.stop_bits == 1 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                    number_of_ones = nnz(data);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                 Onestop_parity_idle_bit = [parity_bit stop_bit  idle_bit];  
                 frame = [ idle_start_bit data Onestop_parity_idle_bit frame2];
                         
               elseif (json_config(1).inputs.stop_bits == 2 && (json_config(1).inputs.parity == "even"||json_config(1).inputs.parity == "odd"))
                      number_of_ones = nnz(data);        %count number of ones in the data
                    if(json_config(1).inputs.parity == "even" && mod(number_of_ones,2) == 0)
                        parity_bit =0;
                    elseif ( (json_config(1).inputs.parity == "even" && mod(number_of_ones,2) ~= 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) == 0))
                        parity_bit =1;
                    elseif ( (json_config(1).inputs.parity == "odd" && mod(number_of_ones,2) ~= 0))
                        parity_bit =0;  
                    end
                    Twostop_parity_idle_bit = [parity_bit stop_bit stop_bit  idle_bit];     
                    frame = [ idle_start_bit data Twostop_parity_idle_bit frame2];                    
               end
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               %Draw UART Frame
               stairs (app.UIAxes,[frame,frame(end)]);    
               
               % Plot Requirments
               nexttile;
               plot(file_size,OUTPUT_UART_PLOT_TotalTxTime);
               title('UART Transmit time');
               xlabel('File Size');
               nexttile;
               plot(file_size,OUTPUT_UART_PLOT_OVERHEAD);
               title('UART Overhead');
               xlabel('File Size');
               ylabel('Overhead');               
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
              else
              app.Label.Text = "Error: Undefined Protocol Entry";
              app.Label.FontColor = [1.00,0.00,0.00];             
              frame2 = [ 0 0 0 0];
              stairs (app.UIAxes,[frame2,frame2(end)]); 
              end
              
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              %Generate output.json
              % Convert to JSON text
              jsonText2 = jsonencode(OUTPUT,'PrettyPrint',true)
              % Write to a json file
              fid = fopen('ELC3030_8.json', 'w');
              fprintf(fid, '%s', jsonText2);
              fclose(fid);
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' UART Frame')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'USB Packets')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UARTUSBEMULATORLabel = uilabel(app.UIFigure);
            app.UARTUSBEMULATORLabel.Text = ' UART / USB EMULATOR';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ' ';
            app.MembersPanel = uipanel(app.UIFigure);
            app.MembersPanel.Title = 'Members';
            app.AyaRedaAbdallahSaiedAbdelazizMohammadLabel = uilabel(app.MembersPanel);
            app.AyaRedaAbdallahSaiedAbdelazizMohammadLabel.Text = {'- Aya Reda'; ''; '- Abdallah Saied'; ''; '- Abdelaziz Mohammad'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroProjectAppTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end